classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        KonvolusiTab                matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
        CalculateButton             matlab.ui.control.Button
        OutputLabel                 matlab.ui.control.Label
        InputImageButton            matlab.ui.control.Button
        XLabel                      matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        MASKLabel                   matlab.ui.control.Label
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        FilterTab                   matlab.ui.container.Tab
        Label_2                     matlab.ui.control.Label
        nLabel                      matlab.ui.control.Label
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        CalculateButton_2           matlab.ui.control.Button
        OutputLabel_2               matlab.ui.control.Label
        InputImageButton_2          matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        BrighteningTab              matlab.ui.container.Tab
        EditField_5                 matlab.ui.control.NumericEditField
        BrightLabel                 matlab.ui.control.Label
        CalculateButton_3           matlab.ui.control.Button
        OutputLabel_3               matlab.ui.control.Label
        InputImageButton_3          matlab.ui.control.Button
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        NoiseFilterTab              matlab.ui.container.Tab
        filteringLabel              matlab.ui.control.Label
        EditField_6                 matlab.ui.control.NumericEditField
        DropDown_3                  matlab.ui.control.DropDown
        DropDown_2                  matlab.ui.control.DropDown
        OutputLabel_4               matlab.ui.control.Label
        NoiseButton                 matlab.ui.control.Button
        CalculateButton_4           matlab.ui.control.Button
        InputImageButton_4          matlab.ui.control.Button
        UIAxes_9                    matlab.ui.control.UIAxes
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        NoisePeriodicTab            matlab.ui.container.Tab
        PeriodicFilterButton        matlab.ui.control.Button
        UIAxes_21                   matlab.ui.control.UIAxes
        UIAxes_20                   matlab.ui.control.UIAxes
        UIAxes_19                   matlab.ui.control.UIAxes
        UIAxes_18                   matlab.ui.control.UIAxes
        UIAxes_17                   matlab.ui.control.UIAxes
        UIAxes_16                   matlab.ui.control.UIAxes
        UIAxes_15                   matlab.ui.control.UIAxes
        UIAxes_14                   matlab.ui.control.UIAxes
        UIAxes_13                   matlab.ui.control.UIAxes
        UIAxes_12                   matlab.ui.control.UIAxes
        UIAxes_11                   matlab.ui.control.UIAxes
        UIAxes_10                   matlab.ui.control.UIAxes
        WienerTab                   matlab.ui.container.Tab
        EditField_7                 matlab.ui.control.NumericEditField
        KLabel                      matlab.ui.control.Label
        CalculateButton_5           matlab.ui.control.Button
        InputImageButton_5          matlab.ui.control.Button
        MotionBluredImageLabel      matlab.ui.control.Label
        OutputLabel_5               matlab.ui.control.Label
        UIAxes_24                   matlab.ui.control.UIAxes
        UIAxes_23                   matlab.ui.control.UIAxes
        UIAxes_22                   matlab.ui.control.UIAxes
        Tugas2PemrosesanCitraLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImageInput1 = nan
        ImageInput2 = nan
        ImageInput3 = nan
        ImageInput4 = nan
        Image4Noise = nan
        ImageInput5 = nan
        ImagePSF = nan
        ImageBlurred = nan
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes_2)
            disableDefaultInteractivity(app.UIAxes_3)
            disableDefaultInteractivity(app.UIAxes_4)
            disableDefaultInteractivity(app.UIAxes_5)
            disableDefaultInteractivity(app.UIAxes_6)
            disableDefaultInteractivity(app.UIAxes_7)
            disableDefaultInteractivity(app.UIAxes_8)
            disableDefaultInteractivity(app.UIAxes_9)
            disableDefaultInteractivity(app.UIAxes_10)
            disableDefaultInteractivity(app.UIAxes_11)
            disableDefaultInteractivity(app.UIAxes_12)
            disableDefaultInteractivity(app.UIAxes_13)
            disableDefaultInteractivity(app.UIAxes_14)
            disableDefaultInteractivity(app.UIAxes_15)
            disableDefaultInteractivity(app.UIAxes_16)
            disableDefaultInteractivity(app.UIAxes_17)
            disableDefaultInteractivity(app.UIAxes_18)
            disableDefaultInteractivity(app.UIAxes_19)
            disableDefaultInteractivity(app.UIAxes_20)
            disableDefaultInteractivity(app.UIAxes_21)
            disableDefaultInteractivity(app.UIAxes_22)
            disableDefaultInteractivity(app.UIAxes_23)
            disableDefaultInteractivity(app.UIAxes_24)


        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput1 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_3);
            end
        end

        % Button pushed function: InputImageButton_2
        function InputImageButton_2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput2 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            pembilang = app.EditField.Value;
            penyebut = app.EditField_2.Value;
            if(penyebut == 0)
                warndlg("Deteminator value should not be 0","Input")
            end
            pengali = pembilang/penyebut;
            inputmask = app.TextArea.Value;
            inputmask = inputmask(inputmask ~= "");
            row = length(inputmask);
            checkmatrix = false;
            col = 0;
            for i = 1: row
                arrnow = strsplit(inputmask{i},' ');
                arrnow = arrnow(arrnow ~= "");
                colnow = length(arrnow);
                if(colnow ~= row)
                    checkmatrix = false;
                    break
                else
                    checkmatrix = true;
                    col = colnow;
                end
            end
            if(checkmatrix == false)
                warndlg("Input Matrix Not True or Not Square", "Input")
            else
                if(mod(row,2) ~= 0)
                    checknum = true;
                    maskmatrix = zeros(row,colnow);
                    for i = 1: row
                        arrnow = strsplit(inputmask{i},' ');
                        arrnow = arrnow(arrnow ~= "");
                        colnow = length(arrnow);
                        for j = 1:colnow
                            checknumnow= str2double(arrnow{j});
                            if(isnan(checknumnow))
                                checknum = false;
                                break
                            else
                                maskmatrix(i,j) = checknumnow;
                            end
                        end
                    end
                    if(checknum == false)
                        warndlg("Input Matrix Must Number", "Input")
                    else
                        maskmatrixfinal = pengali * maskmatrix;
                        if(isnan(app.ImageInput1))
                            warndlg("Must Input Image First", "Input")
                        else
                            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                            'Indeterminate','on');
                            hasil = konvolusi(im2double(app.ImageInput1),double(maskmatrixfinal));
                            hasil = im2uint8(hasil);
                            imshow(hasil,'Parent',app.UIAxes_4);
                            close(d)
                        end
                    end
                else
                    warndlg("Row And Column Must Odd", "Input")
                end
            end


        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if(isnan(app.ImageInput2))
                warndlg("Must Input Image First", "Input")
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
                if(app.DropDown.Value == "Mean Filter")
                    if(mod(app.EditField_3.Value,2)==0)
                        warndlg("Input N Must Odd","Input")
                    else
                        hasil = meanfilter(app.ImageInput2,app.EditField_3.Value);
                        imshow(hasil,'Parent',app.UIAxes_2)
                    end
                elseif(app.DropDown.Value == "Gaussian Filter")
                    if(mod(app.EditField_3.Value,2)==0)
                        warndlg("Input N Must Odd","Input")
                    else
                        hasil = gaussfilter(app.ImageInput2,app.EditField_3.Value,app.EditField_4.Value);
                        imshow(hasil,'Parent',app.UIAxes_2)
                    end
                elseif(app.DropDown.Value == "Ideal Low Pass Filter")
 
                    hasil = ilpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Gaussian Low Pass Filter")
 
                    hasil = glpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Butterworth Low Pass Filter")
 
                    hasil = blpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Ideal High Pass Filter")
 
                    hasil = ihpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Gaussian High Pass Filter")
 
                    hasil = ghpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                elseif(app.DropDown.Value == "Butterworth High Pass Filter")
 
                    hasil = bhpf(app.ImageInput2,app.EditField_3.Value);
                    imshow(hasil,'Parent',app.UIAxes_2)
                close(d)
                end
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(value == "Mean Filter")
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "off";
                app.nLabel.Visible = "on";
                app.nLabel.Text = "n";
                app.Label_2.Visible = "off";
            elseif(value == "Gaussian Filter")
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "on";
                app.nLabel.Visible = "on";
                app.Label_2.Visible = "on";
                app.nLabel.Text = "n";
                app.Label_2.Text = "σ";
            else
                app.EditField_3.Visible = "on";
                app.EditField_4.Visible = "off";
                app.nLabel.Visible = "on";
                app.nLabel.Text = "D";
                app.Label_2.Visible = "off";
            end
        end

        % Button pushed function: InputImageButton_3
        function InputImageButton_3Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput3 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_5);
            end
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            if(isnan(app.ImageInput3))
                warndlg("Must Input Image First", "Input")
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
                hasil = frequency_brightening(app.ImageInput3,app.EditField_5.Value);
                imshow(hasil,'Parent',app.UIAxes_6)
                close(d)
            end
        end

        % Button pushed function: InputImageButton_4
        function InputImageButton_4Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput4 = imread(fullpath);
                imshow(image,'Parent',app.UIAxes_7);
            end
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            if(isnan(app.ImageInput4))
                warndlg("Must Input Image First", "Input")
            else
                if(app.DropDown_3.Value == "Gaussian Noise")
                    app.Image4Noise = noise_gaussian(app.ImageInput4);
                    imshow(app.Image4Noise,'Parent',app.UIAxes_8)
                else
                    app.Image4Noise = noise_snp(app.ImageInput4);
                    imshow(app.Image4Noise,'Parent',app.UIAxes_8)
                end
            end
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            if(isnan(app.ImageInput4))
                if(isnan(app.Image4Noise))
                    warndlg("Must Input Image First and Make Noise To Image", "Input")
                end
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
                if(app.DropDown_2.Value == "Min Filter")
                    hasil = noise_filtering(app.Image4Noise,0,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                elseif(app.DropDown_2.Value == "Max Filter")
                    hasil = noise_filtering(app.Image4Noise,1,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                elseif(app.DropDown_2.Value == "Median Filter")
                    hasil = noise_filtering(app.Image4Noise,2,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Arithmetic Mean Filter")
                    hasil = noise_filtering(app.Image4Noise,3,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Geometric Mean Filter")
                    hasil = noise_filtering(app.Image4Noise,4,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Harmonic Mean Filter")
                    hasil = noise_filtering(app.Image4Noise,5,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Contraharmonic Mean Filter")
                    hasil = noise_filtering(app.Image4Noise,6,app.EditField_6.Value);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Midpoint Filter")
                    hasil = noise_filtering(app.Image4Noise,7,0);
                    imshow(hasil,'Parent',app.UIAxes_9)
                 elseif(app.DropDown_2.Value == "Alpha Trimmed Mean Filter")
                    hasil = noise_filtering(app.Image4Noise,8,app.EditField_6.Value);
                    imshow(hasil,'Parent',app.UIAxes_9)
                end
                close(d)
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if(value == "Contraharmonic Mean Filter")
                app.filteringLabel.Visible = "on";
                app.EditField_6.Visible = "on";
            elseif(value == "Alpha Trimmed Mean Filter")
                app.filteringLabel.Visible = "on";
                app.EditField_6.Visible = "on";
            else
                app.filteringLabel.Visible = "off";
                app.EditField_6.Visible = "off";
            end
        end

        % Button pushed function: PeriodicFilterButton
        function PeriodicFilterButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
            imshow(imread("lena_periodic.jpeg"),'Parent',app.UIAxes_10);
            imshow(imread("moon_periodic.tif"),'Parent',app.UIAxes_12);
            imshow(imread("castle_periodic.jpeg"),'Parent',app.UIAxes_14);
            imshow(imread("bird_periodic.jpeg"),'Parent',app.UIAxes_16);
            imshow(imread("robot_periodic.jpg"),'Parent',app.UIAxes_18);
            imshow(imread("car_periodic.png"),'Parent',app.UIAxes_20);


            hasillena = periodic_lena();
            hasilmoon = periodic_moon();
            hasilcastle = periodic_castle();
            hasilbird = periodic_bird();
            hasilcar = periodic_car();
            hasilrobot = periodic_robot();
            
            imshow(hasillena,'Parent',app.UIAxes_11)
            imshow(hasilmoon,'Parent',app.UIAxes_13)
            imshow(hasilcastle,'Parent',app.UIAxes_15)
            imshow(hasilbird,'Parent',app.UIAxes_17)
            imshow(hasilrobot,'Parent',app.UIAxes_19)
            imshow(hasilcar,'Parent',app.UIAxes_21)
            close(d)


        end

        % Button pushed function: InputImageButton_5
        function InputImageButton_5Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.png'},"Open File");
            fullpath = strcat(pathname,filename);
            if(fullpath == "")
                warndlg("File Not Found")
            else
                image = imread(fullpath);
                app.ImageInput5 = imread(fullpath);
                lenblur = 20;
                tethablur = 45;
                app.ImagePSF = fspecial('motion',lenblur,tethablur);
                bluredimage = imfilter(image,app.ImagePSF,'circular','conv');
                app.ImageBlurred = bluredimage;
                imshow(image,'Parent',app.UIAxes_22);
                imshow(bluredimage,'Parent',app.UIAxes_23);
            end
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            % WIENER
            if (isnan(app.ImageInput5))
                warndlg("Must Input Image First", "Input")
            else
                d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Indeterminate','on');
                hasilwiener = wiener(app.ImageBlurred,app.ImagePSF,app.EditField_7.Value);
                imshow(hasilwiener,'Parent',app.UIAxes_24);
                close(d)
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tugas2PemrosesanCitraLabel = uilabel(app.UIFigure);
            app.Tugas2PemrosesanCitraLabel.Text = 'Tugas 2 Pemrosesan Citra';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.KonvolusiTab = uitab(app.TabGroup);
            app.KonvolusiTab.Title = 'Konvolusi';
            app.UIAxes_3 = uiaxes(app.KonvolusiTab);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.KonvolusiTab);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.MASKLabel = uilabel(app.KonvolusiTab);
            app.MASKLabel.Text = 'MASK = ';
            app.EditField = uieditfield(app.KonvolusiTab, 'numeric');
            app.EditField.Value = 1;
            app.Label = uilabel(app.KonvolusiTab);
            app.Label.Text = '___';
            app.EditField_2 = uieditfield(app.KonvolusiTab, 'numeric');
            app.EditField_2.Value = 1;
            app.XLabel = uilabel(app.KonvolusiTab);
            app.XLabel.Text = 'X';
            app.InputImageButton = uibutton(app.KonvolusiTab, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.OutputLabel = uilabel(app.KonvolusiTab);
            app.OutputLabel.Text = 'Output';
            app.CalculateButton = uibutton(app.KonvolusiTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TextArea = uitextarea(app.KonvolusiTab);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.UIAxes = uiaxes(app.FilterTab);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.FilterTab);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.DropDown = uidropdown(app.FilterTab);
            app.DropDown.Items = {'Mean Filter', 'Gaussian Filter', 'Ideal Low Pass Filter', 'Gaussian Low Pass Filter', 'Butterworth Low Pass Filter', 'Ideal High Pass Filter', 'Gaussian High Pass Filter', 'Butterworth High Pass Filter'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Mean Filter';
            app.InputImageButton_2 = uibutton(app.FilterTab, 'push');
            app.InputImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_2Pushed, true);
            app.InputImageButton_2.Text = 'Input Image';
            app.OutputLabel_2 = uilabel(app.FilterTab);
            app.OutputLabel_2.Text = 'Output';
            app.CalculateButton_2 = uibutton(app.FilterTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.EditField_3 = uieditfield(app.FilterTab, 'numeric');
            app.EditField_4 = uieditfield(app.FilterTab, 'numeric');
            app.EditField_4.Visible = 'off';
            app.nLabel = uilabel(app.FilterTab);
            app.nLabel.Text = 'n';
            app.Label_2 = uilabel(app.FilterTab);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'σ';
            app.BrighteningTab = uitab(app.TabGroup);
            app.BrighteningTab.Title = 'Brightening';
            app.UIAxes_5 = uiaxes(app.BrighteningTab);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.BrighteningTab);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.InputImageButton_3 = uibutton(app.BrighteningTab, 'push');
            app.InputImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_3Pushed, true);
            app.InputImageButton_3.Text = 'Input Image';
            app.OutputLabel_3 = uilabel(app.BrighteningTab);
            app.OutputLabel_3.Text = 'Output';
            app.CalculateButton_3 = uibutton(app.BrighteningTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.BrightLabel = uilabel(app.BrighteningTab);
            app.BrightLabel.Text = 'Bright';
            app.EditField_5 = uieditfield(app.BrighteningTab, 'numeric');
            app.NoiseFilterTab = uitab(app.TabGroup);
            app.NoiseFilterTab.Title = 'Noise Filter';
            app.UIAxes_7 = uiaxes(app.NoiseFilterTab);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.NoiseFilterTab);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.NoiseFilterTab);
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.InputImageButton_4 = uibutton(app.NoiseFilterTab, 'push');
            app.InputImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_4Pushed, true);
            app.InputImageButton_4.Text = 'Input Image';
            app.CalculateButton_4 = uibutton(app.NoiseFilterTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.NoiseButton = uibutton(app.NoiseFilterTab, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.OutputLabel_4 = uilabel(app.NoiseFilterTab);
            app.OutputLabel_4.Text = 'Output';
            app.DropDown_2 = uidropdown(app.NoiseFilterTab);
            app.DropDown_2.Items = {'Min Filter', 'Max Filter', 'Median Filter', 'Arithmetic Mean Filter', 'Geometric Mean Filter', 'Harmonic Mean Filter', 'Contraharmonic Mean Filter', 'Midpoint Filter', 'Alpha Trimmed Mean Filter'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Min Filter';
            app.DropDown_3 = uidropdown(app.NoiseFilterTab);
            app.DropDown_3.Items = {'Gaussian Noise', 'Salt and Paper Noise'};
            app.DropDown_3.Value = 'Gaussian Noise';
            app.EditField_6 = uieditfield(app.NoiseFilterTab, 'numeric');
            app.EditField_6.Visible = 'off';
            app.filteringLabel = uilabel(app.NoiseFilterTab);
            app.filteringLabel.Visible = 'off';
            app.filteringLabel.Text = 'filtering';
            app.NoisePeriodicTab = uitab(app.TabGroup);
            app.NoisePeriodicTab.Title = 'Noise Periodic';
            app.UIAxes_10 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_13 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_16 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_17 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.UIAxes_18 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_18, 'Z')
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.UIAxes_19 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.YTick = [];
            app.UIAxes_20 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_20, 'Z')
            app.UIAxes_20.XTick = [];
            app.UIAxes_20.YTick = [];
            app.UIAxes_21 = uiaxes(app.NoisePeriodicTab);
            zlabel(app.UIAxes_21, 'Z')
            app.UIAxes_21.XTick = [];
            app.UIAxes_21.YTick = [];
            app.PeriodicFilterButton = uibutton(app.NoisePeriodicTab, 'push');
            app.PeriodicFilterButton.ButtonPushedFcn = createCallbackFcn(app, @PeriodicFilterButtonPushed, true);
            app.PeriodicFilterButton.Text = 'Periodic Filter';
            app.WienerTab = uitab(app.TabGroup);
            app.WienerTab.Title = 'Wiener';
            app.UIAxes_22 = uiaxes(app.WienerTab);
            zlabel(app.UIAxes_22, 'Z')
            app.UIAxes_22.XTick = [];
            app.UIAxes_22.YTick = [];
            app.UIAxes_23 = uiaxes(app.WienerTab);
            zlabel(app.UIAxes_23, 'Z')
            app.UIAxes_23.XTick = [];
            app.UIAxes_23.YTick = [];
            app.UIAxes_24 = uiaxes(app.WienerTab);
            zlabel(app.UIAxes_24, 'Z')
            app.UIAxes_24.XTick = [];
            app.UIAxes_24.YTick = [];
            app.OutputLabel_5 = uilabel(app.WienerTab);
            app.OutputLabel_5.Text = 'Output';
            app.MotionBluredImageLabel = uilabel(app.WienerTab);
            app.MotionBluredImageLabel.Text = 'Motion Blured Image';
            app.InputImageButton_5 = uibutton(app.WienerTab, 'push');
            app.InputImageButton_5.ButtonPushedFcn = createCallbackFcn(app, @InputImageButton_5Pushed, true);
            app.InputImageButton_5.Text = 'Input Image';
            app.CalculateButton_5 = uibutton(app.WienerTab, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.KLabel = uilabel(app.WienerTab);
            app.KLabel.Text = 'K';
            app.EditField_7 = uieditfield(app.WienerTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end